classdef main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        insert_tf_button       matlab.ui.control.Button
        delay_text             matlab.ui.control.Label
        text58                 matlab.ui.control.Label
        Gnum_text              matlab.ui.control.Label
        text26                 matlab.ui.control.Label
        text25                 matlab.ui.control.Label
        Gden_text              matlab.ui.control.Label
        task_selected_text     matlab.ui.control.Label
        uibuttongroup11        matlab.ui.container.ButtonGroup
        alpha_text             matlab.ui.control.Label
        Ta_text                matlab.ui.control.Label
        N_text                 matlab.ui.control.Label
        Td_text                matlab.ui.control.Label
        Ti_text                matlab.ui.control.Label
        text16                 matlab.ui.control.Label
        text15                 matlab.ui.control.Label
        text14                 matlab.ui.control.Label
        text13                 matlab.ui.control.Label
        text12                 matlab.ui.control.Label
        Kp_text                matlab.ui.control.Label
        text10                 matlab.ui.control.Label
        uibuttongroup10        matlab.ui.container.ButtonGroup
        text38                 matlab.ui.control.Label
        text37                 matlab.ui.control.Label
        C3den_text             matlab.ui.control.Label
        C3num_text             matlab.ui.control.Label
        text34                 matlab.ui.control.Label
        text33                 matlab.ui.control.Label
        C2den_text             matlab.ui.control.Label
        C2num_text             matlab.ui.control.Label
        text30                 matlab.ui.control.Label
        text29                 matlab.ui.control.Label
        Cden_text              matlab.ui.control.Label
        Cnum_text              matlab.ui.control.Label
        uibuttongroup7         matlab.ui.container.ButtonGroup
        Pm_text                matlab.ui.control.Label
        Gm_text                matlab.ui.control.Label
        text54                 matlab.ui.control.Label
        text53                 matlab.ui.control.Label
        Ms_text                matlab.ui.control.Label
        text51                 matlab.ui.control.Label
        uibuttongroup6         matlab.ui.container.ButtonGroup
        settlingtime_text      matlab.ui.control.Label
        risetime_text          matlab.ui.control.Label
        undershoot_text        matlab.ui.control.Label
        overshoot_text         matlab.ui.control.Label
        text45                 matlab.ui.control.Label
        text43                 matlab.ui.control.Label
        text42                 matlab.ui.control.Label
        text41                 matlab.ui.control.Label
        IAE_text               matlab.ui.control.Label
        text39                 matlab.ui.control.Label
        pushButton_Start       matlab.ui.control.Button
        uibuttongroup5         matlab.ui.container.ButtonGroup
        edit_Delay             matlab.ui.control.EditField
        text57                 matlab.ui.control.Label
        edit_Numerator         matlab.ui.control.EditField
        edit_Denominator       matlab.ui.control.EditField
        text5                  matlab.ui.control.Label
        text4                  matlab.ui.control.Label
        uibuttongroup3         matlab.ui.container.ButtonGroup
        MaxGeneration_popmenu  matlab.ui.control.DropDown
        PopSize_popmenu        matlab.ui.control.DropDown
        uibuttongroup2         matlab.ui.container.ButtonGroup
        SetPoint               matlab.ui.control.RadioButton
        LoadDisturbance        matlab.ui.control.RadioButton
        uibuttongroup1         matlab.ui.container.ButtonGroup
        Ms14                   matlab.ui.control.RadioButton
        Ms2                    matlab.ui.control.RadioButton
        popupmenu_Controller   matlab.ui.control.DropDown
        sensitivity_plot       matlab.ui.control.UIAxes
        bode_plot              matlab.ui.control.UIAxes
        stepResponse_plot      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to main (see VARARGIN)
            
            % Choose default command line output for main
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes main wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            global Ms_selected
            global popmenu_choice
            global Task_selected
            global popmenuPopSize_choice
            global popmenuMaxGen_choice
            global Numerator
            global Denominator
            global NoErrorCheck
            global minimum_IAE;
            global Delay
            
            minimum_IAE=100;
            Ms_selected = 1.4;
            popmenu_choice = 0;
            Task_selected = 1;
            popmenuPopSize_choice = 0;
            popmenuMaxGen_choice = 0;
            Numerator = NaN;
            Denominator = NaN;
            Delay = NaN;
            NoErrorCheck = 0;
        end

        % Value changed function: MaxGeneration_popmenu
        function MaxGeneration_popmenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global popmenuMaxGen_choice
            contents=cellstr(get(hObject,'String'));
            pop_choice_temp2=contents(get(hObject,'Value'));
            if(strcmp(pop_choice_temp2,'10'))
                popmenuMaxGen_choice = 10;
            elseif (strcmp(pop_choice_temp2,'200'))
                popmenuMaxGen_choice = 200;
            elseif (strcmp(pop_choice_temp2,'500'))
                popmenuMaxGen_choice = 500;
            elseif (strcmp(pop_choice_temp2,'1000'))
                popmenuMaxGen_choice = 1000;
            else
                popmenuMaxGen_choice = 0;
            end
        end

        % Value changed function: PopSize_popmenu
        function PopSize_popmenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global popmenuPopSize_choice
            contents=cellstr(get(hObject,'String'));
            pop_choice_temp1=contents(get(hObject,'Value'));
            if(strcmp(pop_choice_temp1,'10'))
                popmenuPopSize_choice = 10;
            elseif (strcmp(pop_choice_temp1,'50'))
                popmenuPopSize_choice = 50;
            elseif (strcmp(pop_choice_temp1,'100'))
                popmenuPopSize_choice = 100;
            elseif (strcmp(pop_choice_temp1,'200'))
                popmenuPopSize_choice = 200;
            elseif (strcmp(pop_choice_temp1,'500'))
                popmenuPopSize_choice = 500;
            else
                popmenuPopSize_choice = 0;
            end
        end

        % Value changed function: edit_Delay
        function edit_Delay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Delay
            tmp_delay = string(get(handles.edit_Delay,'String'));
            Delay = str2double(tmp_delay);
        end

        % Value changed function: edit_Denominator
        function edit_Denominator_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Denominator
            userDenominatorInput = string(get(handles.edit_Denominator,'String'));
            tmp = strsplit(userDenominatorInput,',');
            Denominator = str2double(tmp);
        end

        % Value changed function: edit_Numerator
        function edit_Numerator_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Numerator
            userNumeratorInput = string(get(handles.edit_Numerator,'String'));
            tmp = strsplit(userNumeratorInput,',');
            Numerator = str2double(tmp);
        end

        % Button pushed function: insert_tf_button
        function insert_tf_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Numerator
            global Denominator
            global Delay
            
            
            if isnan(Numerator)
                msgbox('Please insert the numerator', 'Error', 'error');
                return;
            elseif isnan(Denominator)
                msgbox('Please insert the denominator', 'Error', 'error');
                return;
            elseif isnan(Delay)
                msgbox('Please insert the delay, (0 = no delay)', 'Error', 'error');
                return;
            else
                % Process Transfer Function
                [str_NumProcess,str_DenProcess] = tf2string(Numerator,Denominator);
                set(handles.Gnum_text,'String',str_NumProcess);
                set(handles.Gden_text,'String',str_DenProcess);
                set(handles.delay_text,'String',strcat("-",string(Delay),"s"));
            end


            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        % Value changed function: popupmenu_Controller
        function popupmenu_Controller_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global popmenuController_choice
            contents=cellstr(get(hObject,'String'));
            pop_choice_temp=contents(get(hObject,'Value'));
            if(strcmp(pop_choice_temp,'PID'))
                popmenuController_choice = 1;
            elseif (strcmp(pop_choice_temp,'I-PD'))
                popmenuController_choice = 2;
            elseif (strcmp(pop_choice_temp,'PI-D'))
                popmenuController_choice = 3;
            elseif (strcmp(pop_choice_temp,'PIDA'))
                popmenuController_choice = 4;
            else
                popmenuController_choice = 0;
            end
        end

        % Button pushed function: pushButton_Start
        function pushButton_Start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Numerator
            global Denominator
            global popmenuController_choice
            global Ms_selected
            global Task_selected
            global popmenuPopSize_choice
            global popmenuMaxGen_choice
            global Delay
            
            NoErrorCheck = checkInputValidity(Numerator, Denominator, popmenuController_choice, popmenuMaxGen_choice, popmenuPopSize_choice, Delay);
            
            analized = struct;
            
            
            %time step
            dt = 0.0001;
            global minimum_IAE;
            minimum_IAE = 1000;
            % If no error are encountered
            if NoErrorCheck
               % Process Transfer Function
               G = tf(Numerator,Denominator)
               analized.systemTransferFunction = G;
               PopSize = popmenuPopSize_choice;
               MaxGeneration = popmenuMaxGen_choice;
               Ms = Ms_selected;
            
               % Process Transfer Function
               Gnum = tf(Numerator,1);
               str_Gnum = printout_TF(Gnum); str_Gnum = erase(str_Gnum,"/ ( 1)");
               str_Gnum=regexprep(str_Gnum,'[()]','');
               set(handles.Gnum_text,'String',str_Gnum);
               Gden = tf(Denominator,1);
               str_Gden = printout_TF(Gden); str_Gden = erase(str_Gden,"/ ( 1)");
               str_Gden=regexprep(str_Gden,'[()]','');
               set(handles.Gden_text,'String',str_Gden);
            
               switch popmenuController_choice
                   case 1
                       % PID Controller
                       switch Task_selected
                           case 1
                               % Set Point Following
                               set(handles.task_selected_text,'String','PID - Setpoint Following')
                               [analized, ClosedLoop_PID_sp, Loop_PID_sp] = pid_setpoint(PopSize, MaxGeneration, dt, G, Ms);
                               analized.Name = 'PID - Setpoint Following';
                               axes(handles.stepResponse_plot); plot_closedloop_stepresponse(ClosedLoop_PID_sp,analized.Name);
                               axes(handles.bode_plot); plot_bodeplot(Loop_PID_sp);
                               axes(handles.sensitivity_plot); plot_sensitivity(Loop_PID_sp);
                               % Controller parameter text plot
                               set(handles.Kp_text,'String',string(analized.pid.Kp))
                               set(handles.Ti_text,'String',string(analized.pid.Ti))
                               set(handles.Td_text,'String',string(analized.pid.Td))
                               set(handles.N_text,'String',string(analized.pid.N))
                               set(handles.Ta_text,'String',"/")
                               set(handles.alpha_text,'String',"/")
                               % Controller Transfer Function text plot
                               [str_NumPID,str_DenPID] = tf2string(analized.Controller.pid.Numerator,analized.Controller.pid.Denominator);
                               set(handles.Cnum_text,'String',str_NumPID);
                               set(handles.Cden_text,'String',str_DenPID);
                               set(handles.C2num_text,'String',"[/]");
                               set(handles.C2den_text,'String',"[/]");
                               set(handles.C3num_text,'String',"[/]");
                               set(handles.C3den_text,'String',"[/]");
                               % Frequency characteristics text plot
                               [pid_gainMargin,pid_phaseMargin] = margin(analized.Loop.pid);
                               set(handles.Ms_text,'String',string(analized.Sensitivity.pid));
                               set(handles.Gm_text,'String',string(pid_gainMargin));
                               set(handles.Pm_text,'String',string(pid_phaseMargin));
                               % Step Response characteristics text plot
                               set(handles.IAE_text,'String',string(analized.pid.IAE));
                               set(handles.undershoot_text,'String',string(analized.pid.undershoot));
                               set(handles.settlingtime_text,'String',string(analized.pid.settlingtime));
                               set(handles.overshoot_text,'String',string(analized.pid.overshoot));
                               set(handles.risetime_text,'String',string(analized.pid.Risetime));
                               % Disable
                               set(handles.insert_tf_button,'Enable','off')
                               set(handles.pushButton_Start,'Enable','off')
                               set(handles.popupmenu_Controller,'Enable','off')
                               set(handles.edit_Numerator,'Enable','off')
                               set(handles.edit_Denominator,'Enable','off')
                               set(handles.edit_Delay,'Enable','off')
                               set(handles.Ms14,'Enable','off')
                               set(handles.Ms2,'Enable','off')
                               set(handles.SetPoint,'Enable','off')
                               set(handles.LoadDisturbance,'Enable','off')
                               set(handles.PopSize_popmenu,'Enable','off')
                               set(handles.MaxGeneration_popmenu,'Enable','off')
                           case 2
                               % Load Disturbance Rejection
                               set(handles.task_selected_text,'String','PID - Load Disturbance Rejection')
                               [analized, Loop_PID_ld, Disturb_PID_ld] = pid_loaddisturbance(PopSize, MaxGeneration, dt, G, Ms);
                               analized.Name = 'PID - Load Disturbance Rejection';
                               axes(handles.stepResponse_plot); plot_closedloop_stepresponse(Disturb_PID_ld,analized.Name);
                               axes(handles.bode_plot); plot_bodeplot(Loop_PID_ld);
                               axes(handles.sensitivity_plot); plot_sensitivity(Loop_PID_ld);
                               % Controller parameter text plot
                               set(handles.Kp_text,'String',string(analized.pid_dist.Kp))
                               set(handles.Ti_text,'String',string(analized.pid_dist.Ti))
                               set(handles.Td_text,'String',string(analized.pid_dist.Td))
                               set(handles.N_text,'String',string(analized.pid_dist.N))
                               set(handles.Ta_text,'String',"/")
                               set(handles.alpha_text,'String',"/")
                               % Controller Transfer Function text plot
                               [str_NumPID,str_DenPID] = tf2string(analized.Controller.pid.Numerator,analized.Controller.pid.Denominator);
                               set(handles.Cnum_text,'String',str_NumPID);
                               set(handles.Cden_text,'String',str_DenPID);
                               set(handles.C2num_text,'String',"[/]");
                               set(handles.C2den_text,'String',"[/]");
                               set(handles.C3num_text,'String',"[/]");
                               set(handles.C3den_text,'String',"[/]");
                               % Frequency characteristics text plot
                               [pid_gainMargin,pid_phaseMargin] = margin(analized.Loop.pid);
                               set(handles.Ms_text,'String',string(analized.Sensitivity.pid));
                               set(handles.Gm_text,'String',string(pid_gainMargin));
                               set(handles.Pm_text,'String',string(pid_phaseMargin));
                               % Load disturbance characteristics text plot
                               set(handles.IAE_text,'String',string(analized.pid_dist.IAE));
                               set(handles.undershoot_text,'String',string(analized.pid_dist.undershoot));
                               set(handles.settlingtime_text,'String',string(analized.pid_dist.settlingtime));
                               set(handles.overshoot_text,'String',string(analized.pid_dist.overshoot));
                               set(handles.risetime_text,'String',string(analized.pid_dist.Risetime));
                               % Disable
                               set(handles.insert_tf_button,'Enable','off')
                               set(handles.pushButton_Start,'Enable','off')
                               set(handles.popupmenu_Controller,'Enable','off')
                               set(handles.edit_Numerator,'Enable','off')
                               set(handles.edit_Denominator,'Enable','off')
                               set(handles.edit_Delay,'Enable','off')
                               set(handles.Ms14,'Enable','off')
                               set(handles.Ms2,'Enable','off')
                               set(handles.SetPoint,'Enable','off')
                               set(handles.LoadDisturbance,'Enable','off')
                               set(handles.PopSize_popmenu,'Enable','off')
                               set(handles.MaxGeneration_popmenu,'Enable','off')
                       end
                   case 2
                       % I-PD Controller
                       switch Task_selected
                           case 1
                               % Set Point Following
                               set(handles.task_selected_text,'String','I-PD - Setpoint Following')
                               [analized, ClosedLoop_IPD_sp, Loop_IPD_sp] = ipd_setpoint(PopSize, MaxGeneration, dt, G, Ms);
                               analized.Name = 'I-PD - Setpoint Following';
                               axes(handles.stepResponse_plot); plot_closedloop_stepresponse(ClosedLoop_IPD_sp,analized.Name);
                               axes(handles.bode_plot); plot_bodeplot(Loop_IPD_sp);
                               axes(handles.sensitivity_plot); plot_sensitivity(Loop_IPD_sp);
                               % Controller parameter text plot
                               set(handles.Kp_text,'String',string(analized.ipd.Kp))
                               set(handles.Ti_text,'String',string(analized.ipd.Ti))
                               set(handles.Td_text,'String',string(analized.ipd.Td))
                               set(handles.N_text,'String',string(analized.ipd.N))
                               set(handles.Ta_text,'String',"/")
                               set(handles.alpha_text,'String',"/")
                               % Controller Transfer Function text plot
                               [str_NumI_PD1,str_DenI_PD1] = tf2string(analized.Controller.ipd.K1.Numerator,analized.Controller.ipd.K1.Denominator);
                               set(handles.Cnum_text,'String',str_NumI_PD1);
                               set(handles.Cden_text,'String',str_DenI_PD1);
                               [str_NumI_PD2,str_DenI_PD2] = tf2string(analized.Controller.ipd.K2.Numerator,analized.Controller.ipd.K2.Denominator);
                               set(handles.C2num_text,'String',str_NumI_PD2);
                               set(handles.C2den_text,'String',str_DenI_PD2);
                               set(handles.C3num_text,'String',"[/]");
                               set(handles.C3den_text,'String',"[/]");
                               % Frequency characteristics text plot
                               [ipd_gainMargin,ipd_phaseMargin] = margin(analized.Loop.ipd);
                               set(handles.Ms_text,'String',string(analized.Sensitivity.ipd));
                               set(handles.Gm_text,'String',string(ipd_gainMargin));
                               set(handles.Pm_text,'String',string(ipd_phaseMargin));
                               % Step Response characteristics text plot
                               set(handles.IAE_text,'String',string(analized.ipd.IAE));
                               set(handles.undershoot_text,'String',string(analized.ipd.undershoot));
                               set(handles.settlingtime_text,'String',string(analized.ipd.settlingtime));
                               set(handles.overshoot_text,'String',string(analized.ipd.overshoot));
                               set(handles.risetime_text,'String',string(analized.ipd.Risetime));
                               % Disable
                               set(handles.insert_tf_button,'Enable','off')
                               set(handles.pushButton_Start,'Enable','off')
                               set(handles.popupmenu_Controller,'Enable','off')
                               set(handles.edit_Numerator,'Enable','off')
                               set(handles.edit_Denominator,'Enable','off')
                               set(handles.edit_Delay,'Enable','off')
                               set(handles.Ms14,'Enable','off')
                               set(handles.Ms2,'Enable','off')
                               set(handles.SetPoint,'Enable','off')
                               set(handles.LoadDisturbance,'Enable','off')
                               set(handles.PopSize_popmenu,'Enable','off')
                               set(handles.MaxGeneration_popmenu,'Enable','off')
                           case 2
                               % Load Disturbance Rejection
                               set(handles.task_selected_text,'String','I-PD - Load Disturbance Rejection')
                               [analized, Loop_I_PD_ld, Disturb_I_PD_ld] = pid_loaddisturbance(PopSize, MaxGeneration, dt, G, Ms);
                               analized.Name = 'I-PD - Load Disturbance Rejection';
                               axes(handles.stepResponse_plot); plot_closedloop_stepresponse(Disturb_I_PD_ld,analized.Name);
                               axes(handles.bode_plot); plot_bodeplot(Loop_I_PD_ld);
                               axes(handles.sensitivity_plot); plot_sensitivity(Loop_I_PD_ld);
                               % Controller parameter text plot
                               set(handles.Kp_text,'String',string(analized.pid_dist.Kp))
                               set(handles.Ti_text,'String',string(analized.pid_dist.Ti))
                               set(handles.Td_text,'String',string(analized.pid_dist.Td))
                               set(handles.N_text,'String',string(analized.pid_dist.N))
                               set(handles.Ta_text,'String',"/")
                               set(handles.alpha_text,'String',"/")
                               % Controller Transfer Function text plot
                               [str_NumPID,str_DenPID] = tf2string(analized.Controller.pid.Numerator,analized.Controller.pid.Denominator);
                               set(handles.Cnum_text,'String',str_NumPID);
                               set(handles.Cden_text,'String',str_DenPID);
                               set(handles.C2num_text,'String',"[/]");
                               set(handles.C2den_text,'String',"[/]");
                               set(handles.C3num_text,'String',"[/]");
                               set(handles.C3den_text,'String',"[/]");
                               % Frequency characteristics text plot
                               [pid_gainMargin,pid_phaseMargin] = margin(analized.Loop.pid);
                               set(handles.Ms_text,'String',string(analized.Sensitivity.pid));
                               set(handles.Gm_text,'String',string(pid_gainMargin));
                               set(handles.Pm_text,'String',string(pid_phaseMargin));
                               % Disable
                               set(handles.insert_tf_button,'Enable','off')
                               set(handles.pushButton_Start,'Enable','off')
                               set(handles.popupmenu_Controller,'Enable','off')
                               set(handles.edit_Numerator,'Enable','off')
                               set(handles.edit_Denominator,'Enable','off')
                               set(handles.edit_Delay,'Enable','off')
                               set(handles.Ms14,'Enable','off')
                               set(handles.Ms2,'Enable','off')
                               set(handles.SetPoint,'Enable','off')
                               set(handles.LoadDisturbance,'Enable','off')
                               set(handles.PopSize_popmenu,'Enable','off')
                               set(handles.MaxGeneration_popmenu,'Enable','off')
                       end
                   case 3
                       % PI-D Controller
                       switch Task_selected
                           case 1
                               % Set Point Following
                               set(handles.task_selected_text,'String','PI-D - Setpoint Following')
                               [analized, ClosedLoop_PI_D_sp, Loop_PI_D_sp] = pi_d_setpoint(PopSize, MaxGeneration, dt, G, Ms);
                               analized.Name = 'PI-D - Setpoint Following';
                               axes(handles.stepResponse_plot); plot_closedloop_stepresponse(ClosedLoop_PI_D_sp,analized.Name);
                               axes(handles.bode_plot); plot_bodeplot(Loop_PI_D_sp);
                               axes(handles.sensitivity_plot); plot_sensitivity(Loop_PI_D_sp);
                               % Controller parameter text plot
                               set(handles.Kp_text,'String',string(analized.dpi.Kp))
                               set(handles.Ti_text,'String',string(analized.dpi.Ti))
                               set(handles.Td_text,'String',string(analized.dpi.Td))
                               set(handles.N_text,'String',string(analized.dpi.N))
                               set(handles.Ta_text,'String',"/")
                               set(handles.alpha_text,'String',"/")
                               % Controller Transfer Function text plot
                               [str_NumPI_D1,~] = tf2string(analized.Controller.dpi.K1,analized.Controller.dpi.K1);
                               set(handles.Cnum_text,'String',str_NumPI_D1);
                               set(handles.Cden_text,'String',"1");
                               [str_NumPI_D2,str_DenPI_D2] = tf2string(analized.Controller.dpi.K2.Numerator,analized.Controller.dpi.K2.Denominator);
                               set(handles.C2num_text,'String',str_NumPI_D2);
                               set(handles.C2den_text,'String',str_DenPI_D2);
                               [str_NumPI_D3,str_DenPI_D3] = tf2string(analized.Controller.dpi.K3.Numerator,analized.Controller.dpi.K3.Denominator);
                               set(handles.C3num_text,'String',str_NumPI_D3);
                               set(handles.C3den_text,'String',str_DenPI_D3);
                               % Frequency characteristics text plot
                               [pi_d_gainMargin,pi_d_phaseMargin] = margin(analized.Loop.dpi);
                               set(handles.Ms_text,'String',string(analized.Sensitivity.dpi));
                               set(handles.Gm_text,'String',string(pi_d_gainMargin));
                               set(handles.Pm_text,'String',string(pi_d_phaseMargin));
                               % Step Response characteristics text plot
                               set(handles.IAE_text,'String',string(analized.dpi.IAE));
                               set(handles.undershoot_text,'String',string(analized.dpi.undershoot));
                               set(handles.settlingtime_text,'String',string(analized.dpi.settlingtime));
                               set(handles.overshoot_text,'String',string(analized.dpi.overshoot));
                               set(handles.risetime_text,'String',string(analized.dpi.Risetime));
                               % Disable
                               set(handles.insert_tf_button,'Enable','off')
                               set(handles.pushButton_Start,'Enable','off')
                               set(handles.popupmenu_Controller,'Enable','off')
                               set(handles.edit_Numerator,'Enable','off')
                               set(handles.edit_Denominator,'Enable','off')
                               set(handles.edit_Delay,'Enable','off')
                               set(handles.Ms14,'Enable','off')
                               set(handles.Ms2,'Enable','off')
                               set(handles.SetPoint,'Enable','off')
                               set(handles.LoadDisturbance,'Enable','off')
                               set(handles.PopSize_popmenu,'Enable','off')
                               set(handles.MaxGeneration_popmenu,'Enable','off')
                           case 2
                               % Load Disturbance Rejection
                               set(handles.task_selected_text,'String','PI-D  - Load Disturbance Rejection')
                               [analized, Loop_PI_D_ld, Disturb_PI_D_ld] = pid_loaddisturbance(PopSize, MaxGeneration, dt, G, Ms);
                               analized.Name = 'PI-D - Load Disturbance Rejection';
                               axes(handles.stepResponse_plot); plot_closedloop_stepresponse(Disturb_PI_D_ld,analized.Name);
                               axes(handles.bode_plot); plot_bodeplot(Loop_PI_D_ld);
                               axes(handles.sensitivity_plot); plot_sensitivity(Loop_PI_D_ld);
                               % Controller parameter text plot
                               set(handles.Kp_text,'String',string(analized.pid_dist.Kp))
                               set(handles.Ti_text,'String',string(analized.pid_dist.Ti))
                               set(handles.Td_text,'String',string(analized.pid_dist.Td))
                               set(handles.N_text,'String',string(analized.pid_dist.N))
                               set(handles.Ta_text,'String',"/")
                               set(handles.alpha_text,'String',"/")
                               % Controller Transfer Function text plot
                               [str_NumPID,str_DenPID] = tf2string(analized.Controller.pid.Numerator,analized.Controller.pid.Denominator);
                               set(handles.Cnum_text,'String',str_NumPID);
                               set(handles.Cden_text,'String',str_DenPID);
                               set(handles.C2num_text,'String',"[/]");
                               set(handles.C2den_text,'String',"[/]");
                               set(handles.C3num_text,'String',"[/]");
                               set(handles.C3den_text,'String',"[/]");
                               % Frequency characteristics text plot
                               [pid_gainMargin,pid_phaseMargin] = margin(analized.Loop.pid);
                               set(handles.Ms_text,'String',string(analized.Sensitivity.pid));
                               set(handles.Gm_text,'String',string(pid_gainMargin));
                               set(handles.Pm_text,'String',string(pid_phaseMargin));
                               % Disable
                               set(handles.insert_tf_button,'Enable','off')
                               set(handles.pushButton_Start,'Enable','off')
                               set(handles.popupmenu_Controller,'Enable','off')
                               set(handles.edit_Numerator,'Enable','off')
                               set(handles.edit_Denominator,'Enable','off')
                               set(handles.edit_Delay,'Enable','off')
                               set(handles.Ms14,'Enable','off')
                               set(handles.Ms2,'Enable','off')
                               set(handles.SetPoint,'Enable','off')
                               set(handles.LoadDisturbance,'Enable','off')
                               set(handles.PopSize_popmenu,'Enable','off')
                               set(handles.MaxGeneration_popmenu,'Enable','off')
                       end
                   case 4
                       % PIDA Controller
                       switch Task_selected
                           case 1
                               % Set Point Following
                               set(handles.task_selected_text,'String','PIDA - Setpoint Following')
                               [analized, ClosedLoop_PIDA_sp, Loop_PIDA_sp] = pida_setpoint(PopSize, MaxGeneration, dt, G, Ms);
                               analized.Name = 'PIDA  Setpoint Following';
                               axes(handles.stepResponse_plot); plot_closedloop_stepresponse(ClosedLoop_PIDA_sp,analized.Name);
                               axes(handles.bode_plot); plot_bodeplot(Loop_PIDA_sp);
                               axes(handles.sensitivity_plot); plot_sensitivity(Loop_PIDA_sp);
                               % Controller parameter text plot
                               set(handles.Kp_text,'String',string(analized.pida.Kp))
                               set(handles.Ti_text,'String',string(analized.pida.Ti))
                               set(handles.Td_text,'String',string(analized.pida.Td))
                               set(handles.N_text,'String',string(analized.pida.N))
                               set(handles.Ta_text,'String',string(analized.pida.Ta))
                               set(handles.alpha_text,'String',string(analized.pida.alfa))
                               % Controller Transfer Function text plot
                               [str_NumPIDA,str_DenPIDA] = tf2string(analized.Controller.pida.Numerator,analized.Controller.pida.Denominator);
                               set(handles.Cnum_text,'String',str_NumPIDA);
                               set(handles.Cden_text,'String',str_DenPIDA);
                               set(handles.C2num_text,'String',"[/]");
                               set(handles.C2den_text,'String',"[/]");
                               set(handles.C3num_text,'String',"[/]");
                               set(handles.C3den_text,'String',"[/]");
                               % Frequency characteristics text plot
                               [pida_gainMargin,pida_phaseMargin] = margin(analized.Loop.pida);
                               set(handles.Ms_text,'String',string(analized.Sensitivity.pida));
                               set(handles.Gm_text,'String',string(pida_gainMargin));
                               set(handles.Pm_text,'String',string(pida_phaseMargin));
                               % Step Response characteristics text plot
                               set(handles.IAE_text,'String',string(analized.pida.IAE));
                               set(handles.undershoot_text,'String',string(analized.pida.undershoot));
                               set(handles.settlingtime_text,'String',string(analized.pida.settlingtime));
                               set(handles.overshoot_text,'String',string(analized.pida.overshoot));
                               set(handles.risetime_text,'String',string(analized.pida.Risetime));
                               % Disable
                               set(handles.insert_tf_button,'Enable','off')
                               set(handles.pushButton_Start,'Enable','off')
                               set(handles.popupmenu_Controller,'Enable','off')
                               set(handles.edit_Numerator,'Enable','off')
                               set(handles.edit_Denominator,'Enable','off')
                               set(handles.edit_Delay,'Enable','off')
                               set(handles.Ms14,'Enable','off')
                               set(handles.Ms2,'Enable','off')
                               set(handles.SetPoint,'Enable','off')
                               set(handles.LoadDisturbance,'Enable','off')
                               set(handles.PopSize_popmenu,'Enable','off')
                               set(handles.MaxGeneration_popmenu,'Enable','off')
                           case 2
                               % Load Disturbance Rejection
                               set(handles.task_selected_text,'String','PIDA  - Load Disturbance Rejection')
                               [analized, Disturb_PIDA_ld, Loop_PIDA_ld] = pida_loaddisturbance(PopSize, MaxGeneration, dt, G, Ms);
                               analized.Name = 'PIDA - Load Disturbance Rejection';
                               axes(handles.stepResponse_plot); plot_closedloop_stepresponse(Disturb_PIDA_ld,analized.Name);
                               axes(handles.bode_plot); plot_bodeplot(Loop_PIDA_ld);
                               axes(handles.sensitivity_plot); plot_sensitivity(Loop_PIDA_ld);
                               % Controller parameter text plot
                               set(handles.Kp_text,'String',string(analized.pida_dist.Kp))
                               set(handles.Ti_text,'String',string(analized.pida_dist.Ti))
                               set(handles.Td_text,'String',string(analized.pida_dist.Td))
                               set(handles.N_text,'String',string(analized.pida_dist.N))
                               set(handles.Ta_text,'String',string(analized.pida_dist.Ta))
                               set(handles.alpha_text,'String',string(analized.pida_dist.alfa))
                               [str_NumPIDA,str_DenPIDA] = tf2string(analized.Controller.pida.Numerator,analized.Controller.pida.Denominator);
                               set(handles.Cnum_text,'String',str_NumPIDA);
                               set(handles.Cden_text,'String',str_DenPIDA);
                               set(handles.C2num_text,'String',"[/]");
                               set(handles.C2den_text,'String',"[/]");
                               set(handles.C3num_text,'String',"[/]");
                               set(handles.C3den_text,'String',"[/]");
                               % Frequency characteristics text plot
                               [pida_gainMargin,pida_phaseMargin] = margin(analized.Loop.pida);
                               set(handles.Ms_text,'String',string(analized.Sensitivity.pida));
                               set(handles.Gm_text,'String',string(pida_gainMargin));
                               set(handles.Pm_text,'String',string(pida_phaseMargin));
                               % Disable
                               set(handles.insert_tf_button,'Enable','off')
                               set(handles.pushButton_Start,'Enable','off')
                               set(handles.popupmenu_Controller,'Enable','off')
                               set(handles.edit_Numerator,'Enable','off')
                               set(handles.edit_Denominator,'Enable','off')
                               set(handles.edit_Delay,'Enable','off')
                               set(handles.Ms14,'Enable','off')
                               set(handles.Ms2,'Enable','off')
                               set(handles.SetPoint,'Enable','off')
                               set(handles.LoadDisturbance,'Enable','off')
                               set(handles.PopSize_popmenu,'Enable','off')
                               set(handles.MaxGeneration_popmenu,'Enable','off')
                       end
               end
            
            ms_print_excel(analized, popmenuController_choice, Ms, Task_selected);
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'main';
            app.figure1.Tag = 'figure1';
            app.stepResponse_plot = uiaxes(app.figure1);
            app.stepResponse_plot.Tag = 'stepResponse_plot';
            app.bode_plot = uiaxes(app.figure1);
            app.bode_plot.Tag = 'bode_plot';
            app.sensitivity_plot = uiaxes(app.figure1);
            app.sensitivity_plot.Tag = 'sensitivity_plot';
            app.popupmenu_Controller = uidropdown(app.figure1);
            app.popupmenu_Controller.Items = {'Select a Controller', 'PID', 'I-PD', 'PI-D', 'PIDA'};
            app.popupmenu_Controller.ValueChangedFcn = createCallbackFcn(app, @popupmenu_Controller_Callback, true);
            app.popupmenu_Controller.Tag = 'popupmenu_Controller';
            app.popupmenu_Controller.Value = 'Select a Controller';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'Maximum Sensitivy';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.Ms2 = uiradiobutton(app.uibuttongroup1);
            app.Ms2.Tag = 'Ms2';
            app.Ms2.Text = 'Ms = 2';
            app.Ms14 = uiradiobutton(app.uibuttongroup1);
            app.Ms14.Tag = 'Ms14';
            app.Ms14.Text = 'Ms = 1.4';
            app.Ms14.Value = true;
            app.uibuttongroup2 = uibuttongroup(app.figure1);
            app.uibuttongroup2.Title = 'Task Optimization';
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.LoadDisturbance = uiradiobutton(app.uibuttongroup2);
            app.LoadDisturbance.Tag = 'LoadDisturbance';
            app.LoadDisturbance.Text = 'Load Disturbance Rejection';
            app.SetPoint = uiradiobutton(app.uibuttongroup2);
            app.SetPoint.Tag = 'SetPoint';
            app.SetPoint.Text = 'Setpoint following';
            app.SetPoint.Value = true;
            app.uibuttongroup3 = uibuttongroup(app.figure1);
            app.uibuttongroup3.Title = 'GA Parameters';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.PopSize_popmenu = uidropdown(app.uibuttongroup3);
            app.PopSize_popmenu.Items = {'Select Population Size', '10', '50', '100', '200', '500'};
            app.PopSize_popmenu.ValueChangedFcn = createCallbackFcn(app, @PopSize_popmenu_Callback, true);
            app.PopSize_popmenu.Tag = 'PopSize_popmenu';
            app.PopSize_popmenu.Value = 'Select Population Size';
            app.MaxGeneration_popmenu = uidropdown(app.uibuttongroup3);
            app.MaxGeneration_popmenu.Items = {'Select Maximum Generation', '10', '200', '500', '1000'};
            app.MaxGeneration_popmenu.ValueChangedFcn = createCallbackFcn(app, @MaxGeneration_popmenu_Callback, true);
            app.MaxGeneration_popmenu.Tag = 'MaxGeneration_popmenu';
            app.MaxGeneration_popmenu.Value = 'Select Maximum Generation';
            app.uibuttongroup5 = uibuttongroup(app.figure1);
            app.uibuttongroup5.Title = 'System Transfer Function';
            app.uibuttongroup5.Tag = 'uibuttongroup5';
            app.text4 = uilabel(app.uibuttongroup5);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Numerator';
            app.text5 = uilabel(app.uibuttongroup5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Denominator';
            app.edit_Denominator = uieditfield(app.uibuttongroup5, 'text');
            app.edit_Denominator.ValueChangedFcn = createCallbackFcn(app, @edit_Denominator_Callback, true);
            app.edit_Denominator.Tag = 'edit_Denominator';
            app.edit_Denominator.Value = 'Edit Text';
            app.edit_Numerator = uieditfield(app.uibuttongroup5, 'text');
            app.edit_Numerator.ValueChangedFcn = createCallbackFcn(app, @edit_Numerator_Callback, true);
            app.edit_Numerator.Tag = 'edit_Numerator';
            app.edit_Numerator.Value = 'Edit Text';
            app.text57 = uilabel(app.uibuttongroup5);
            app.text57.Tag = 'text57';
            app.text57.Text = 'Delay';
            app.edit_Delay = uieditfield(app.uibuttongroup5, 'text');
            app.edit_Delay.ValueChangedFcn = createCallbackFcn(app, @edit_Delay_Callback, true);
            app.edit_Delay.Tag = 'edit_Delay';
            app.edit_Delay.Value = 'Edit Text';
            app.pushButton_Start = uibutton(app.figure1, 'push');
            app.pushButton_Start.ButtonPushedFcn = createCallbackFcn(app, @pushButton_Start_Callback, true);
            app.pushButton_Start.Tag = 'pushButton_Start';
            app.pushButton_Start.Text = 'Start Simulation';
            app.uibuttongroup6 = uibuttongroup(app.figure1);
            app.uibuttongroup6.Title = 'Step Response Characteristics';
            app.uibuttongroup6.Tag = 'uibuttongroup6';
            app.text39 = uilabel(app.uibuttongroup6);
            app.text39.Tag = 'text39';
            app.text39.Text = 'IAE';
            app.IAE_text = uilabel(app.uibuttongroup6);
            app.IAE_text.Tag = 'IAE_text';
            app.IAE_text.Text = '';
            app.text41 = uilabel(app.uibuttongroup6);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Overshoot';
            app.text42 = uilabel(app.uibuttongroup6);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Undershoot';
            app.text43 = uilabel(app.uibuttongroup6);
            app.text43.Tag = 'text43';
            app.text43.Text = 'Rise Time';
            app.text45 = uilabel(app.uibuttongroup6);
            app.text45.Tag = 'text45';
            app.text45.Text = 'Settling Time';
            app.overshoot_text = uilabel(app.uibuttongroup6);
            app.overshoot_text.Tag = 'overshoot_text';
            app.overshoot_text.Text = '';
            app.undershoot_text = uilabel(app.uibuttongroup6);
            app.undershoot_text.Tag = 'undershoot_text';
            app.undershoot_text.Text = '';
            app.risetime_text = uilabel(app.uibuttongroup6);
            app.risetime_text.Tag = 'risetime_text';
            app.risetime_text.Text = '';
            app.settlingtime_text = uilabel(app.uibuttongroup6);
            app.settlingtime_text.Tag = 'settlingtime_text';
            app.settlingtime_text.Text = '';
            app.uibuttongroup7 = uibuttongroup(app.figure1);
            app.uibuttongroup7.Title = 'Bode Plot Characteristics';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.text51 = uilabel(app.uibuttongroup7);
            app.text51.Tag = 'text51';
            app.text51.Text = 'Maximum Sensitivity';
            app.Ms_text = uilabel(app.uibuttongroup7);
            app.Ms_text.Tag = 'Ms_text';
            app.Ms_text.Text = '';
            app.text53 = uilabel(app.uibuttongroup7);
            app.text53.Tag = 'text53';
            app.text53.Text = 'Gain Margin';
            app.text54 = uilabel(app.uibuttongroup7);
            app.text54.Tag = 'text54';
            app.text54.Text = 'Phase Margin';
            app.Gm_text = uilabel(app.uibuttongroup7);
            app.Gm_text.Tag = 'Gm_text';
            app.Gm_text.Text = '';
            app.Pm_text = uilabel(app.uibuttongroup7);
            app.Pm_text.Tag = 'Pm_text';
            app.Pm_text.Text = '';
            app.uibuttongroup10 = uibuttongroup(app.figure1);
            app.uibuttongroup10.Title = 'Transfer Functions';
            app.uibuttongroup10.Tag = 'uibuttongroup10';
            app.Cnum_text = uilabel(app.uibuttongroup10);
            app.Cnum_text.Tag = 'Cnum_text';
            app.Cnum_text.Text = 'Simulation in progress ...';
            app.Cden_text = uilabel(app.uibuttongroup10);
            app.Cden_text.Tag = 'Cden_text';
            app.Cden_text.Text = 'Simulation in progress ...';
            app.text29 = uilabel(app.uibuttongroup10);
            app.text29.Tag = 'text29';
            app.text29.Text = '------------------------------------------------------------';
            app.text30 = uilabel(app.uibuttongroup10);
            app.text30.Tag = 'text30';
            app.text30.Text = {''; ''; 'C1(s) = '};
            app.C2num_text = uilabel(app.uibuttongroup10);
            app.C2num_text.Tag = 'C2num_text';
            app.C2num_text.Text = 'Simulation ...';
            app.C2den_text = uilabel(app.uibuttongroup10);
            app.C2den_text.Tag = 'C2den_text';
            app.C2den_text.Text = 'Simulation ...';
            app.text33 = uilabel(app.uibuttongroup10);
            app.text33.Tag = 'text33';
            app.text33.Text = '------------------';
            app.text34 = uilabel(app.uibuttongroup10);
            app.text34.Tag = 'text34';
            app.text34.Text = {''; ''; 'C2(s) = '};
            app.C3num_text = uilabel(app.uibuttongroup10);
            app.C3num_text.Tag = 'C3num_text';
            app.C3num_text.Text = 'Simulation ...';
            app.C3den_text = uilabel(app.uibuttongroup10);
            app.C3den_text.Tag = 'C3den_text';
            app.C3den_text.Text = 'Simulation  ...';
            app.text37 = uilabel(app.uibuttongroup10);
            app.text37.Tag = 'text37';
            app.text37.Text = '------------------';
            app.text38 = uilabel(app.uibuttongroup10);
            app.text38.Tag = 'text38';
            app.text38.Text = {''; ''; 'C3(s) = '};
            app.uibuttongroup11 = uibuttongroup(app.figure1);
            app.uibuttongroup11.Title = 'Controller parameters';
            app.uibuttongroup11.Tag = 'uibuttongroup11';
            app.text10 = uilabel(app.uibuttongroup11);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Kp';
            app.Kp_text = uilabel(app.uibuttongroup11);
            app.Kp_text.Tag = 'Kp_text';
            app.Kp_text.Text = '';
            app.text12 = uilabel(app.uibuttongroup11);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Ti';
            app.text13 = uilabel(app.uibuttongroup11);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Td';
            app.text14 = uilabel(app.uibuttongroup11);
            app.text14.Tag = 'text14';
            app.text14.Text = 'N';
            app.text15 = uilabel(app.uibuttongroup11);
            app.text15.Tag = 'text15';
            app.text15.Text = 'alpha';
            app.text16 = uilabel(app.uibuttongroup11);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Ta';
            app.Ti_text = uilabel(app.uibuttongroup11);
            app.Ti_text.Tag = 'Ti_text';
            app.Ti_text.Text = '';
            app.Td_text = uilabel(app.uibuttongroup11);
            app.Td_text.Tag = 'Td_text';
            app.Td_text.Text = '';
            app.N_text = uilabel(app.uibuttongroup11);
            app.N_text.Tag = 'N_text';
            app.N_text.Text = '';
            app.Ta_text = uilabel(app.uibuttongroup11);
            app.Ta_text.Tag = 'Ta_text';
            app.Ta_text.Text = '';
            app.alpha_text = uilabel(app.uibuttongroup11);
            app.alpha_text.Tag = 'alpha_text';
            app.alpha_text.Text = '';
            app.task_selected_text = uilabel(app.figure1);
            app.task_selected_text.Tag = 'task_selected_text';
            app.task_selected_text.Text = '';
            app.Gden_text = uilabel(app.figure1);
            app.Gden_text.Tag = 'Gden_text';
            app.Gden_text.Text = 'Insert Denominator';
            app.text25 = uilabel(app.figure1);
            app.text25.Tag = 'text25';
            app.text25.Text = '----------------------------';
            app.text26 = uilabel(app.figure1);
            app.text26.Tag = 'text26';
            app.text26.Text = {''; ''; 'P(s) = '};
            app.Gnum_text = uilabel(app.figure1);
            app.Gnum_text.Tag = 'Gnum_text';
            app.Gnum_text.Text = 'Insert Numerator';
            app.text58 = uilabel(app.figure1);
            app.text58.Tag = 'text58';
            app.text58.Text = {''; 'e'};
            app.delay_text = uilabel(app.figure1);
            app.delay_text.Tag = 'delay_text';
            app.delay_text.Text = '- [ ] s';
            app.insert_tf_button = uibutton(app.figure1, 'push');
            app.insert_tf_button.ButtonPushedFcn = createCallbackFcn(app, @insert_tf_button_Callback, true);
            app.insert_tf_button.Tag = 'insert_tf_button';
            app.insert_tf_button.Text = 'Insert Transfer Function';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)main_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end